classdef DisplayInputOutput < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        FileTab                         matlab.ui.container.Tab
        NameEditFieldLabel              matlab.ui.control.Label
        NameEditField                   matlab.ui.control.EditField
        PlotPanel                       matlab.ui.container.Panel
        ColormapDropDownLabel           matlab.ui.control.Label
        ColormapDropDown                matlab.ui.control.DropDown
        SavePlotButton                  matlab.ui.control.Button
        FolderEditFieldLabel            matlab.ui.control.Label
        FolderEditField                 matlab.ui.control.EditField
        ExportIntensityxyxzyzButton     matlab.ui.control.Button
        ExportComplexFieldxyxzyzButton  matlab.ui.control.Button
        ExportIntensityVolumexyzButton  matlab.ui.control.Button
        ExportFieldVolumexyzButton      matlab.ui.control.Button
        SettingTab                      matlab.ui.container.Tab
        InputsamplingLabel              matlab.ui.control.Label
        pixelWHEditFieldLabel           matlab.ui.control.Label
        pixelWHEditField                matlab.ui.control.NumericEditField
        physicalWHmmEditFieldLabel      matlab.ui.control.Label
        physicalWHmmEditField           matlab.ui.control.NumericEditField
        BeamtypeDropDownLabel           matlab.ui.control.Label
        BeamtypeDropDown                matlab.ui.control.DropDown
        LaserLabel                      matlab.ui.control.Label
        WavelengthnmEditFieldLabel      matlab.ui.control.Label
        WavelengthnmEditField           matlab.ui.control.NumericEditField
        PolarizationEditFieldLabel      matlab.ui.control.Label
        PolarizationEditField           matlab.ui.control.EditField
        LensLabel                       matlab.ui.control.Label
        fmmEditFieldLabel               matlab.ui.control.Label
        fmmEditField                    matlab.ui.control.NumericEditField
        NAEditFieldLabel                matlab.ui.control.Label
        NAEditField                     matlab.ui.control.NumericEditField
        MaterialsLabel                  matlab.ui.control.Label
        n1EditFieldLabel                matlab.ui.control.Label
        n1EditField                     matlab.ui.control.NumericEditField
        n2EditFieldLabel                matlab.ui.control.Label
        n2EditField                     matlab.ui.control.NumericEditField
        dmmEditFieldLabel               matlab.ui.control.Label
        dmmEditField                    matlab.ui.control.NumericEditField
        EntrancepupilrmmEditFieldLabel  matlab.ui.control.Label
        EntrancepupilrmmEditField       matlab.ui.control.NumericEditField
        AberrationLabel                 matlab.ui.control.Label
        TabGroup2                       matlab.ui.container.TabGroup
        GaussTab                        matlab.ui.container.Tab
        WxmmEditFieldLabel              matlab.ui.control.Label
        WxmmEditField                   matlab.ui.control.NumericEditField
        WymmEditFieldLabel              matlab.ui.control.Label
        WymmEditField                   matlab.ui.control.NumericEditField
        VortexTab                       matlab.ui.container.Tab
        WxmmEditField_2Label            matlab.ui.control.Label
        WxmmEditField_2                 matlab.ui.control.NumericEditField
        WymmEditField_2Label            matlab.ui.control.Label
        WymmEditField_2                 matlab.ui.control.NumericEditField
        mEditFieldLabel                 matlab.ui.control.Label
        mEditField                      matlab.ui.control.NumericEditField
        BesselTab                       matlab.ui.container.Tab
        WxmmEditField_3Label            matlab.ui.control.Label
        WxmmEditField_3                 matlab.ui.control.NumericEditField
        WymmEditField_3Label            matlab.ui.control.Label
        WymmEditField_3                 matlab.ui.control.NumericEditField
        angledegEditFieldLabel          matlab.ui.control.Label
        angledegEditField               matlab.ui.control.NumericEditField
        UserdefineTab                   matlab.ui.container.Tab
        UserdefineprofilefolderEditFieldLabel  matlab.ui.control.Label
        UserdefineprofilefolderEditField  matlab.ui.control.EditField
        SamplingEditFieldLabel          matlab.ui.control.Label
        SamplingEditField               matlab.ui.control.NumericEditField
        PixelmEditFieldLabel            matlab.ui.control.Label
        PixelmEditField                 matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel         matlab.ui.control.Label
        ThresholdEditField              matlab.ui.control.NumericEditField
        Z1EditFieldLabel                matlab.ui.control.Label
        Z1EditField                     matlab.ui.control.NumericEditField
        Z4EditFieldLabel                matlab.ui.control.Label
        Z4EditField                     matlab.ui.control.NumericEditField
        Z11EditFieldLabel               matlab.ui.control.Label
        Z11EditField                    matlab.ui.control.NumericEditField
        Z22EditFieldLabel               matlab.ui.control.Label
        Z22EditField                    matlab.ui.control.NumericEditField
        Z37EditFieldLabel               matlab.ui.control.Label
        Z37EditField                    matlab.ui.control.NumericEditField
        CalculationTab                  matlab.ui.container.Tab
        OutputsamplingLabel             matlab.ui.control.Label
        pixelOutXEditField_2Label       matlab.ui.control.Label
        pixelOutXEditField              matlab.ui.control.NumericEditField
        xOut1mEditFieldLabel            matlab.ui.control.Label
        xOut1EditField                  matlab.ui.control.NumericEditField
        xOut2mEditFieldLabel            matlab.ui.control.Label
        xOut2EditField                  matlab.ui.control.NumericEditField
        pixelOutZEditField_2Label       matlab.ui.control.Label
        pixelOutZEditField              matlab.ui.control.NumericEditField
        zOut1mEditFieldLabel            matlab.ui.control.Label
        zOut1EditField                  matlab.ui.control.NumericEditField
        zOut2mEditFieldLabel            matlab.ui.control.Label
        zOut2EditField                  matlab.ui.control.NumericEditField
        AOIz1mEditFieldLabel            matlab.ui.control.Label
        AOIz1mEditField                 matlab.ui.control.NumericEditField
        AOIz2mEditFieldLabel            matlab.ui.control.Label
        AOIz2mEditField                 matlab.ui.control.NumericEditField
        AOIx1mEditFieldLabel            matlab.ui.control.Label
        AOIx1mEditField                 matlab.ui.control.NumericEditField
        AOIx2mEditFieldLabel            matlab.ui.control.Label
        AOIx2mEditField                 matlab.ui.control.NumericEditField
        xzAxesRatioEditFieldLabel       matlab.ui.control.Label
        xzAxesRatioEditField            matlab.ui.control.NumericEditField
        AreaoftheinterestLabel          matlab.ui.control.Label
        StatsTab                        matlab.ui.container.Tab
        BeamWaistmTextAreaLabel         matlab.ui.control.Label
        BeamWaistmTextArea              matlab.ui.control.TextArea
        UpdateButton                    matlab.ui.control.Button
        RayleighLengthmTextAreaLabel    matlab.ui.control.Label
        RayleighLengthmTextArea         matlab.ui.control.TextArea
        fmmTextAreaLabel                matlab.ui.control.Label
        fmmTextArea                     matlab.ui.control.TextArea
        NATextAreaLabel                 matlab.ui.control.Label
        NATextArea                      matlab.ui.control.TextArea
        n1TextAreaLabel                 matlab.ui.control.Label
        n1TextArea                      matlab.ui.control.TextArea
        n2TextAreaLabel                 matlab.ui.control.Label
        n2TextArea                      matlab.ui.control.TextArea
        dmmTextAreaLabel                matlab.ui.control.Label
        dmmTextArea                     matlab.ui.control.TextArea
        AboutTab                        matlab.ui.container.Tab
        CopyrightCopyrightc2020QingfengLiAllrightsreservedLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_4                        matlab.ui.control.UIAxes
        PSFcalulateButton               matlab.ui.control.Button
        InitializeButton                matlab.ui.control.Button
        DropDown                        matlab.ui.control.DropDown
        PlotButton                      matlab.ui.control.Button
        UIAxes_5                        matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InitializeButton
        function InitializeButtonPushed(app, event)
            %% Inicialize the laser and the beam profile
            wavelength       = app.WavelengthnmEditField.Value * 1e-9;
            laser            = InFocus.tools.Laser(wavelength);
            switch app.PolarizationEditField.Value
                case 'linear_x'
                switch app.BeamtypeDropDown.Value
                    case 'Gauss'                
                        %% Sampling of the incident plane
                        Wx               = app.WxmmEditField.Value * 1e-3;
                        Wy               = app.WymmEditField.Value * 1e-3;
                        physicalWH       = app.physicalWHmmEditField.Value * 1e-3;
                        pixelWH          = app.pixelWHEditField.Value;
                        sectionIn        = InFocus.tools.Section('pixelWH',[pixelWH, pixelWH],...
                               'physicalWH',[physicalWH, physicalWH],...
                               'vector',[0,0,1],'offset',[0,0,0]);
                        sectionIn.E_x    = InFocus.tools.Gauss(sectionIn,[Wx, Wy],[0,0]);
                        intensityIn.all  = abs((sectionIn.E_x).^2);
                    case 'Vortex'
                        Wx               = app.WxmmEditField_2.Value * 1e-3;
                        Wy               = app.WymmEditField_2.Value * 1e-3;
                        physicalWH       = app.physicalWHmmEditField.Value * 1e-3;
                        pixelWH          = app.pixelWHEditField.Value;
                        sectionIn        = InFocus.tools.Section('pixelWH',[pixelWH, pixelWH],...
                               'physicalWH',[physicalWH, physicalWH],...
                               'vector',[0,0,1],'offset',[0,0,0]);
                        sectionIn.E_x    = InFocus.tools.Gauss(sectionIn,[Wx, Wy],[0,0]);
                        m                = app.mEditField.Value;
                        [sectionIn,Ph]   = InFocus.tools.Vortex(sectionIn,laser,m,2*pi);
                        intensityIn.all  = abs((sectionIn.E_x).^2);   
                    case 'Bessel'
                        Wx               = app.WxmmEditField_3.Value * 1e-3;
                        Wy               = app.WymmEditField_3.Value * 1e-3;
                        physicalWH       = app.physicalWHmmEditField.Value * 1e-3;
                        pixelWH          = app.pixelWHEditField.Value;
                        sectionIn        = InFocus.tools.Section('pixelWH',[pixelWH, pixelWH],...
                               'physicalWH',[physicalWH, physicalWH],...
                               'vector',[0,0,1],'offset',[0,0,0]);
                        sectionIn.E_x    = InFocus.tools.Gauss(sectionIn,[Wx, Wy],[0,0]);
                        angle            = app.angledegEditField.Value;
                        [sectionIn,Ph]   = InFocus.tools.Bessel(sectionIn,laser,angle);
                        intensityIn.all  = abs((sectionIn.E_x).^2); 
                    case 'User define'
                        pixelToM         = app.PixelmEditField.Value * 1e-6;
                        Threshold        = app.ThresholdEditField.Value;
                        [intensityUser,EUser, pixelRatio] = InFocus.tools.userDefineImage(app.UserdefineprofilefolderEditField.Value,app.SamplingEditField.Value,pixelToM,Threshold);
                        sectionIn        = Section('pixelWH',[app.SamplingEditField.Value, app.SamplingEditField.Value],...
                               'physicalWH',[app.SamplingEditField.Value*pixelRatio, app.SamplingEditField.Value*pixelRatio],...
                               'vector',[0,0,1],'offset',[0,0,0]);
                        sectionIn.E_x    = EUser;
                        intensityIn.all  = intensityUser;                    
                end
            end
            
            XTickIn      = 1 : (sectionIn.pix_w-1)/5 : sectionIn.pix_w;
            XTickLabelIn = round((- sectionIn.wid/2 : sectionIn.wid/5 : sectionIn.wid/2) * 1e3);
            YTickIn      = 1 : (sectionIn.pix_h-1)/5 : sectionIn.pix_h;
            YTickLabelIn = round((- sectionIn.hei/2 : sectionIn.hei/5 : sectionIn.hei/2) * 1e3);
            %figure(app.UIAxes);
            app.UIAxes.DataAspectRatio = [1 1 1];
            app.UIAxes.PlotBoxAspectRatio = [3 3 4];
            InFocus.tools.PlotIncidentF(app.UIAxes, intensityIn.all, 'entrance.',...
                          XTickIn, XTickLabelIn, YTickIn, YTickLabelIn, app.ColormapDropDown.Value,'linear');
            setappdata(0,'sectionIn',sectionIn);
            setappdata(0,'laser',laser);             
        end

        % Button pushed function: PSFcalulateButton
        function PSFcalulateButtonPushed(app, event)
            clc;
            sectionIn = getappdata(0,'sectionIn');
            laser     = getappdata(0,'laser');
            c         = sectionIn.E_x; % complex amplitude of input light field 
            polarization = app.PolarizationEditField.Value;
            lambda       = laser.lambda0;
            f =  app.fmmEditField.Value * 1e-3;
            NA = app.NAEditField.Value;
            entrancePupil = app.EntrancepupilrmmEditField.Value * 1e-3;
            Z1 = app.Z1EditField.Value;
            Z4 = app.Z4EditField.Value;
            Z11 = app.Z11EditField.Value;
            Z22 = app.Z22EditField.Value;
            Z37 = app.Z37EditField.Value;
            Zernike = [Z1, Z4, Z11, Z22, Z37];
            n1 = app.n1EditField.Value;
            n2 = app.n2EditField.Value;
            nt = app.n2EditField.Value;
            d  = app.dmmEditField.Value * 1e-3;
            kt = 2*pi/lambda*n2;
            
            rhoIncident = sectionIn.wid/(sectionIn.pix_w-1); % pixel size on SLM Plane relayed by a telescope system
            pixIn       = sectionIn.pix_w; % sampling number SLM plane
            
            pixOutX = app.pixelOutXEditField.Value;
            pixOutY = pixOutX;
            xOut1   = app.xOut1EditField.Value * 1e-6;
            xOut2   = app.xOut2EditField.Value * 1e-6;
            yOut1   = xOut1;
            yOut2   = xOut2;
            pixOutZ = app.pixelOutZEditField.Value;
            zOut1   = app.zOut1EditField.Value * 1e-6;
            zOut2   = app.zOut2EditField.Value * 1e-6;
            rhoZ    = (zOut2-zOut1)/(pixOutZ-1); %sampling interval along the z-direction in the focal region unit[m/pixel]
            rhoXY   = (xOut2-xOut1)/(pixOutX-1);    
            rho     = [rhoXY, rhoZ];
            [  I_fout, E_fxout, E_fyout, E_fzout ] = InFocus.tools.PSFapp( c, polarization,...
                                    'laser',[lambda],...
                                    'obj',[NA,f, entrancePupil, Zernike],...
                                    'material',[n1,n2,d],...
                                    'inXY',[rhoIncident,pixIn],...
                                    'outZ',[pixOutZ, rhoZ, zOut1, zOut2],...
                                    'outX',[pixOutX, xOut1, xOut2],...
                                    'outY',[pixOutY, yOut1, yOut2]);
            xmid = fix(pixOutX/2);
            ymid = fix(pixOutY/2);
            intensityZ = squeeze(I_fout(xmid,ymid,:));  
            switch app.BeamtypeDropDown.Value
                case 'Vortex'
                    maxI = max(intensityZ);
                    sumZ = zeros(length(intensityZ),1);
                    for k = 1:length(intensityZ)
                        sumZ(k)=sum(sum(I_fout(:,:,k)));
                    end
                    maxP = round(0.5*(find(sumZ>max(sumZ)/2,1,'first')+find(sumZ>max(sumZ)/2,1,'last')));
                case 'User define'
                    if (contains(app.UserdefineprofilefolderEditField.Value,'vortex')||contains(app.UserdefineprofilefolderEditField.Value,'Vortex'))
                        maxI = max(intensityZ);
                        sumZ = zeros(length(intensityZ),1);
                        for k = 1:length(intensityZ)
                            sumZ(k)=sum(sum(I_fout(:,:,k)));
                        end
                        maxP = round(0.5*(find(sumZ>max(sumZ)/2,1,'first')+find(sumZ>max(sumZ)/2,1,'last')));
                    else
                        [maxI, maxP] = max(intensityZ); 
                    end
                otherwise
                    [maxI, maxP] = max(intensityZ);
            end
            % plot XY at focus
            XTickOutxyf      = 1 : (pixOutX-1)/5 : pixOutX;
            XTickLabelxyf = (xOut1 : (xOut2-xOut1)/5 : xOut2) * 1e6;
            YTickOutxyf      = 1 : (pixOutY-1)/5 : pixOutY;
            YTickLabelxyf = (yOut1 : (yOut2-yOut1)/5 : yOut2) * 1e6;
            app.UIAxes_2.DataAspectRatio = [1 1 1];
            app.UIAxes_2.PlotBoxAspectRatio = [3 3 4];
            InFocus.tools.PlotIncidentF(app.UIAxes_2, I_fout(:,:,maxP), 'focus xy.',...
                          XTickOutxyf, XTickLabelxyf, YTickOutxyf, YTickLabelxyf, app.ColormapDropDown.Value,'linear');
            % Calculate beam wasit
            x = 1:pixOutX;
            y = squeeze(I_fout(xmid,:,maxP));
            beamWasit = InFocus.tools.BeamWidth(x,y)*rhoXY*1e6; % unit [um]           
            % Intensity profile along z   
            z = (-(pixOutZ-1)/2:(pixOutZ-1)/2)*rhoZ*1e6;
            plot (app.UIAxes_3, z, intensityZ/maxI);
            xlabel(app.UIAxes_3,'z/µm');
            ylabel(app.UIAxes_3,'Intensity (a.u.)');
            title(app.UIAxes_3,'Intensity profile along z.');
            % Calculate Rayleigh length
            rayleighLength = length(find(intensityZ>=maxI/2))*rhoZ*1e6; % unit [um]
            % plot XZ at focus
            AOI1           = app.AOIz1mEditField.Value;
            AOI2           = app.AOIz2mEditField.Value;
            centerRange    = (AOI2-AOI1)/(rhoZ*1e6);
            XTickOutz      = 1 : centerRange/5 : centerRange+1;
            XTickLabelz    = AOI1 : (AOI2-AOI1)/5 : AOI2;
            YTickOutz      = 1 : (pixOutX-1)/5 : pixOutX;
            YTickLabelz    = (xOut1 : (xOut2-xOut1)/5 : xOut2) * 1e6;
            intensityXZC   = squeeze(I_fout(:, ymid, (AOI1-app.zOut1EditField.Value)/(rhoZ*1e6)+1:(AOI2-app.zOut1EditField.Value)/(rhoZ*1e6)+1));
            Ex_XZ          = squeeze(E_fxout(:, ymid, (AOI1-app.zOut1EditField.Value)/(rhoZ*1e6)+1:(AOI2-app.zOut1EditField.Value)/(rhoZ*1e6)+1));
            Ey_XZ          = squeeze(E_fyout(:, ymid, (AOI1-app.zOut1EditField.Value)/(rhoZ*1e6)+1:(AOI2-app.zOut1EditField.Value)/(rhoZ*1e6)+1));
            Ez_XZ          = squeeze(E_fzout(:, ymid, (AOI1-app.zOut1EditField.Value)/(rhoZ*1e6)+1:(AOI2-app.zOut1EditField.Value)/(rhoZ*1e6)+1));
            ratio          = centerRange/pixOutX; 
            app.UIAxes_4.DataAspectRatio = [ratio 3.71 1];
            app.UIAxes_4.PlotBoxAspectRatio = [3.71 1 3];
            InFocus.tools.PlotIncidentF(app.UIAxes_4,intensityXZC, 'focus xz.',...
                         XTickOutz, XTickLabelz, YTickOutz, YTickLabelz, app.ColormapDropDown.Value,app.DropDown.Value);
            intensityYZC   = squeeze(I_fout(xmid, :, (AOI1-app.zOut1EditField.Value)/(rhoZ*1e6)+1:(AOI2-app.zOut1EditField.Value)/(rhoZ*1e6)+1));
            Ex_YZ          = squeeze(E_fxout(xmid, :, (AOI1-app.zOut1EditField.Value)/(rhoZ*1e6)+1:(AOI2-app.zOut1EditField.Value)/(rhoZ*1e6)+1));
            Ey_YZ          = squeeze(E_fyout(xmid, :, (AOI1-app.zOut1EditField.Value)/(rhoZ*1e6)+1:(AOI2-app.zOut1EditField.Value)/(rhoZ*1e6)+1));
            Ez_YZ          = squeeze(E_fzout(xmid, :, (AOI1-app.zOut1EditField.Value)/(rhoZ*1e6)+1:(AOI2-app.zOut1EditField.Value)/(rhoZ*1e6)+1));
            ratio          = centerRange/pixOutX; 
            app.UIAxes_5.DataAspectRatio = [ratio 3.71 1];
            app.UIAxes_5.PlotBoxAspectRatio = [3.71 1 3];
            InFocus.tools.PlotIncidentF(app.UIAxes_5,intensityYZC, 'focus yz.',...
                         XTickOutz, XTickLabelz, YTickOutz, YTickLabelz, app.ColormapDropDown.Value,app.DropDown.Value);
            setappdata(0,'intensityXZC',intensityXZC);
            setappdata(0,'intensityYZC',intensityYZC);
            setappdata(0,'intensityXYf',I_fout(:,:,maxP));
            setappdata(0,'Ex_XZ',Ex_XZ);
            setappdata(0,'Ex_YZ',Ex_YZ);
            setappdata(0,'Ex_XY',E_fxout(:,:,maxP));
            setappdata(0,'Ey_XZ',Ey_XZ);
            setappdata(0,'Ey_YZ',Ey_YZ);
            setappdata(0,'Ey_XY',E_fyout(:,:,maxP));
            setappdata(0,'Ez_XZ',Ez_XZ);
            setappdata(0,'Ez_YZ',Ez_YZ);
            setappdata(0,'Ez_XY',E_fzout(:,:,maxP));            
            setappdata(0,'rho',rho);
            setappdata(0,'XTickOutz',XTickOutz);
            setappdata(0,'XTickLabelz',XTickLabelz);
            setappdata(0,'YTickOutz',YTickOutz);
            setappdata(0,'YTickLabelz',YTickLabelz);
            setappdata(0,'I_fout',I_fout);
            setappdata(0,'E_fxout',E_fxout);
            setappdata(0,'E_fyxout',E_fyout);
            setappdata(0,'E_fzout',E_fzout);
            setappdata(0,'beamWasit',beamWasit);
            setappdata(0,'rayleighLength',rayleighLength);
        end

        % Value changed function: pixelWHEditField
        function pixelWHEditFieldValueChanged(app, event)
            value = app.pixelWHEditField.Value;
            
        end

        % Value changed function: physicalWHmmEditField
        function physicalWHmmEditFieldValueChanged(app, event)
            value = app.physicalWHmmEditField.Value;
            
        end

        % Value changed function: WxmmEditField
        function WxmmEditFieldValueChanged(app, event)
            value = app.WxmmEditField.Value;
            
        end

        % Value changed function: WymmEditField
        function WymmEditFieldValueChanged(app, event)
            value = app.WymmEditField.Value;
            
        end

        % Value changed function: WavelengthnmEditField
        function WavelengthnmEditFieldValueChanged(app, event)
            value = app.WavelengthnmEditField.Value;
            
        end

        % Value changed function: PolarizationEditField
        function PolarizationEditFieldValueChanged(app, event)
            value = app.PolarizationEditField.Value;
            
        end

        % Value changing function: PolarizationEditField
        function PolarizationEditFieldValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Value changed function: fmmEditField
        function fmmEditFieldValueChanged(app, event)
            value = app.fmmEditField.Value;
            
        end

        % Value changed function: NAEditField
        function NAEditFieldValueChanged(app, event)
            value = app.NAEditField.Value;
            
        end

        % Value changed function: n1EditField
        function n1EditFieldValueChanged(app, event)
            value = app.n1EditField.Value;
            
        end

        % Value changed function: n2EditField
        function n2EditFieldValueChanged(app, event)
            value = app.n2EditField.Value;
            
        end

        % Value changed function: dmmEditField
        function dmmEditFieldValueChanged(app, event)
            value = app.dmmEditField.Value;
            
        end

        % Value changed function: AOIz1mEditField
        function AOIz1mEditFieldValueChanged(app, event)
            value = app.AOIz1mEditField.Value;
            
        end

        % Value changed function: AOIz2mEditField
        function AOIz2mEditFieldValueChanged(app, event)
            value = app.AOIz2mEditField.Value;
            
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            intensityXZC = getappdata(0,'intensityXZC');
            intensityYZC = getappdata(0,'intensityYZC');
            XTickOutz    = getappdata(0,'XTickOutz');
            XTickLabelz  = getappdata(0,'XTickLabelz');
            YTickOutz    = getappdata(0,'YTickOutz');
            YTickLabelz  = getappdata(0,'YTickLabelz');           
            InFocus.tools.PlotIncidentF(app.UIAxes_4,intensityXZC, 'focus xz.',...
                         XTickOutz, XTickLabelz, YTickOutz, YTickLabelz, app.ColormapDropDown.Value,app.DropDown.Value);
            InFocus.tools.PlotIncidentF(app.UIAxes_5,intensityYZC, 'focus xz.',...
                         XTickOutz, XTickLabelz, YTickOutz, YTickLabelz, app.ColormapDropDown.Value,app.DropDown.Value);        
        end

        % Button pushed function: SavePlotButton
        function SavePlotButtonPushed(app, event)
              fig1 = copyUIAxes(app.UIAxes);
              fig1.figure.Visible = 'off'
              fig2 = copyUIAxes(app.UIAxes_2);
              fig2.figure.Visible = 'off'             
              fig3 = copyUIAxes(app.UIAxes_3);
              fig3.figure.Visible = 'off'
              fig4 = copyUIAxes(app.UIAxes_4);
              fig4.figure.Visible = 'off'
              fig5 = copyUIAxes(app.UIAxes_5);
              fig5.figure.Visible = 'off'              
              cd(app.FolderEditField.Value);
              if ~exist(app.NameEditField.Value,'dir')
                mkdir(app.NameEditField.Value);
              end
              cd(app.NameEditField.Value);
              saveas(fig1.figure,'IncidentXY','png');
              delete(fig1.figure);
              saveas(fig2.figure,'OutputXY','png');
              delete(fig2.figure);
              saveas(fig3.figure,'ProfileXZ','png');
              delete(fig3.figure);
              saveas(fig4.figure,'OutputXZ','png');
              delete(fig4.figure);
              saveas(fig5.figure,'OutputYZ','png');
              delete(fig5.figure);              
              cd ..;
%             fig.Visible = 'off';
%             figAxes = axes(fig);
%             allChildren = app.UIAxes.XAxis.Parent.Children;
%             copyobj(allChildren, figAxes)
%             figAxes.XLim = app.UIAxes.XLim;
%             figAxes.YLim = app.UIAxes.YLim;
%             figAxes.ZLim = app.UIAxes.ZLim;
%             figAxes.Colormap = app.UIAxes.Colormap;
%             figAxes.XTick = app.UIAxes.XTick;
%             figAxes.DataAspectRatio = app.UIAxes.DataAspectRatio;
%             saveas(fig,'name','png');
            %delete(fig);
            
        end

        % Value changed function: NameEditField
        function NameEditFieldValueChanged(app, event)
            value = app.NameEditField.Value;
            
        end

        % Value changed function: zOut2EditField
        function zOut2EditFieldValueChanged(app, event)
            value = app.zOut2EditField.Value;
            
        end

        % Value changed function: zOut1EditField
        function zOut1EditFieldValueChanged(app, event)
            value = app.zOut1EditField.Value;
            
        end

        % Value changed function: pixelOutZEditField
        function pixelOutZEditFieldValueChanged(app, event)
            value = app.pixelOutZEditField.Value;
            
        end

        % Button pushed function: ExportIntensityxyxzyzButton
        function ExportIntensityxyxzyzButtonPushed(app, event)
            %I_fout       = getappdata(0, 'I_fout');
            intensityXZC = getappdata(0, 'intensityXZC');
            intensityYZC = getappdata(0, 'intensityYZC');
            intensityXYf = getappdata(0, 'intensityXYf');
            rho = getappdata(0,'rho');
            cd(app.FolderEditField.Value);
            if ~exist(app.NameEditField.Value,'dir')
                mkdir(app.NameEditField.Value);
            end
            cd(app.NameEditField.Value);
            %dlmwrite ('I_fout.dat',I_fout, 'Delimiter', '\t');
            dlmwrite ('intensityXZC.dat', intensityXZC, 'Delimiter', '\t');
            dlmwrite ('intensityYZC.dat', intensityYZC, 'Delimiter', '\t');
            dlmwrite ('intensityXYf.dat', intensityXYf, 'Delimiter', '\t');
            fid = fopen('pixelRatio.txt','wt');
            fprintf(fid,'rhoXY = %0.3f\n', rho(1)*1e6);
            fprintf(fid,'rhoZ = %0.3f\n', rho(2)*1e6);
            fprintf(fid,'unit: um/pixel');
            cd ..;           
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            I_fout       = getappdata(0, 'I_fout');
            pixOutX      = app.pixelOutXEditField.Value;
            pixOutY      = pixOutX;
            xOut1        = app.xOut1EditField.Value * 1e-6;
            xOut2        = app.xOut2EditField.Value * 1e-6;
            yOut1        = xOut1;
            yOut2        = xOut2;
            pixOutZ      = app.pixelOutZEditField.Value;
            zOut1        = app.zOut1EditField.Value * 1e-6;
            zOut2        = app.zOut2EditField.Value * 1e-6;
            rhoZ         = (zOut2-zOut1)/(pixOutZ-1); %sampling interval along the z-direction in the focal region unit[µm/pixel]
            rhoXY        = (xOut2-xOut1)/(pixOutX-1);    
            AOIz1        = app.AOIz1mEditField.Value;
            AOIz2        = app.AOIz2mEditField.Value;
            zRange1      = (AOIz1-app.zOut1EditField.Value)/(rhoZ*1e6)+1;
            zRange2      = (AOIz2-app.zOut1EditField.Value)/(rhoZ*1e6)+1; % pixel index of AOI
            AOIx1        = app.AOIx1mEditField.Value;
            AOIx2        = app.AOIx2mEditField.Value;
            xRange1      = (AOIx1-app.xOut1EditField.Value)/(rhoXY*1e6)+1;
            xRange2      = (AOIx2-app.xOut1EditField.Value)/(rhoXY*1e6)+1; % pixel index of AOI
            centerRangeZ = (AOIz2-AOIz1)/(rhoZ*1e6);
            centerRangeX = (AOIx2-AOIx1)/(rhoXY*1e6);
            xmid         = fix(pixOutX/2);
            ymid         = fix(pixOutY/2);
            intensityZ   = squeeze(I_fout(xmid,ymid,:));    
            maxI         = max(intensityZ);
            % plot XY at focus
            IXYf         = I_fout(:,:,maxP);
            XTickOutxyf   = 1 : (centerRangeX)/5 : centerRangeX+1;
            XTickLabelxyf = AOIx1 : (AOIx2-AOIx1)/5 : AOIx2;
            YTickOutxyf   = XTickOutxyf;
            YTickLabelxyf = XTickLabelxyf;
            app.UIAxes_2.DataAspectRatio = [1 1 1];
            app.UIAxes_2.PlotBoxAspectRatio = [3 3 4];
            InFocus.tools.PlotIncidentF(app.UIAxes_2, IXYf(xRange1:xRange2,xRange1:xRange2), 'focus xy.',...
                          XTickOutxyf, XTickLabelxyf, YTickOutxyf, YTickLabelxyf, app.ColormapDropDown.Value,'linear');
            % Intensity profile along z          
            z = (-(pixOutZ-1)/2:(pixOutZ-1)/2)*rhoZ*1e6;
            xmid = fix(pixOutX/2);
            ymid = fix(pixOutY/2);
            zmid = fix(pixOutZ/2);
            intensityZ = squeeze(I_fout(xmid,ymid,:));
            %app.UIAxes_3.PlotBoxAspectRatio = [2 1 3];
            plot (app.UIAxes_3, z, intensityZ/maxI);
            xlabel(app.UIAxes_3,'z/µm');
            ylabel(app.UIAxes_3,'Intensity (a.u.)');
            title(app.UIAxes_3,'Intensity profile along z.');
            % plot XZ at focus

            XTickOutz      = 1 : centerRangeZ/5 : centerRangeZ+1;
            XTickLabelz    = AOIz1 : (AOIz2-AOIz1)/5 : AOIz2;
            YTickOutz      = 1 : (centerRangeX)/5 : centerRangeX+1;
            YTickLabelz    = AOIx1 : (AOIx2-AOIx1)/5 : AOIx2;
            intensityXZC   = squeeze(I_fout(:, ymid, zRange1:zRange2));
            intensityXZC   = intensityXZC(xRange1:xRange2, :);
            DataRatio          = centerRangeZ/centerRangeX; 
            app.UIAxes_4.DataAspectRatio = [DataRatio app.xzAxesRatioEditField.Value 1];
            app.UIAxes_4.PlotBoxAspectRatio = [app.xzAxesRatioEditField.Value 1 3];
            InFocus.tools.PlotIncidentF(app.UIAxes_4,intensityXZC, 'focus xz.',...
                         XTickOutz, XTickLabelz, YTickOutz, YTickLabelz, app.ColormapDropDown.Value,app.DropDown.Value);
            intensityYZC   = squeeze(I_fout(xmid, :, zRange1:zRange2));
            intensityYZC   = intensityYZC(xRange1:xRange2, :);
            app.UIAxes_5.DataAspectRatio = [DataRatio app.xzAxesRatioEditField.Value 1];
            app.UIAxes_5.PlotBoxAspectRatio = [app.xzAxesRatioEditField.Value 1 3];
            InFocus.tools.PlotIncidentF(app.UIAxes_5,intensityYZC, 'focus yz.',...
                         XTickOutz, XTickLabelz, YTickOutz, YTickLabelz, app.ColormapDropDown.Value,app.DropDown.Value);
            setappdata(0,'intensityXZC',intensityXZC);
            setappdata(0,'intensityYZC',intensityYZC);
            setappdata(0,'XTickOutz',XTickOutz);
            setappdata(0,'XTickLabelz',XTickLabelz);
            setappdata(0,'YTickOutz',YTickOutz);
            setappdata(0,'YTickLabelz',YTickLabelz);
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            beamWasit = getappdata(0,'beamWasit');
            rayleighLength = getappdata(0,'rayleighLength');
            app.BeamWaistmTextArea.Value = sprintf('%0.2f',beamWasit);
            app.RayleighLengthmTextArea.Value = sprintf('%0.2f',rayleighLength);
            app.fmmTextArea.Value = num2str(app.fmmEditField.Value);
            app.NATextArea.Value = num2str(app.NAEditField.Value);
            app.n1TextArea.Value = num2str(app.n1EditField.Value);
            app.n2TextArea.Value = num2str(app.n2EditField.Value);
            app.dmmTextArea.Value  = num2str(app.dmmEditField.Value);
        end

        % Value changed function: EntrancepupilrmmEditField
        function EntrancepupilrmmEditFieldValueChanged(app, event)
            value = app.EntrancepupilrmmEditField.Value;
            
        end

        % Value changed function: UserdefineprofilefolderEditField
        function UserdefineprofilefolderEditFieldValueChanged(app, event)
            value = app.UserdefineprofilefolderEditField.Value;
            
        end

        % Value changed function: SamplingEditField
        function SamplingEditFieldValueChanged(app, event)
            value = app.SamplingEditField.Value;
            
        end

        % Value changed function: PixelmEditField
        function PixelmEditFieldValueChanged(app, event)
            value = app.PixelmEditField.Value;
            
        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            value = app.ThresholdEditField.Value;
            
        end

        % Selection change function: TabGroup2
        function TabGroup2SelectionChanged(app, event)
            selectedTab = app.TabGroup2.SelectedTab;
            
        end

        % Value changed function: BeamtypeDropDown
        function BeamtypeDropDownValueChanged(app, event)
            value = app.BeamtypeDropDown.Value;
            
        end

        % Value changed function: mEditField
        function mEditFieldValueChanged(app, event)
            value = app.mEditField.Value;
            
        end

        % Value changed function: WxmmEditField_2
        function WxmmEditField_2ValueChanged(app, event)
            value = app.WxmmEditField_2.Value;
            
        end

        % Value changed function: WymmEditField_2
        function WymmEditField_2ValueChanged(app, event)
            value = app.WymmEditField_2.Value;
            
        end

        % Value changed function: Z1EditField
        function Z1EditFieldValueChanged(app, event)
            value = app.Z1EditField.Value;
            
        end

        % Value changed function: Z4EditField
        function Z4EditFieldValueChanged(app, event)
            value = app.Z4EditField.Value;
            
        end

        % Value changed function: Z11EditField
        function Z11EditFieldValueChanged(app, event)
            value = app.Z11EditField.Value;
            
        end

        % Value changed function: Z22EditField
        function Z22EditFieldValueChanged(app, event)
            value = app.Z22EditField.Value;
            
        end

        % Value changed function: Z37EditField
        function Z37EditFieldValueChanged(app, event)
            value = app.Z37EditField.Value;
            
        end

        % Button pushed function: ExportComplexFieldxyxzyzButton
        function ExportComplexFieldxyxzyzButtonPushed(app, event)
            Ex_XZ = getappdata(0, 'Ex_XZ');
            Ex_YZ = getappdata(0, 'Ex_YZ');
            Ex_XY = getappdata(0, 'Ex_XY');
            Ey_XZ = getappdata(0, 'Ey_XZ');
            Ey_YZ = getappdata(0, 'Ey_YZ');
            Ey_XY = getappdata(0, 'Ey_XY'); 
            Ez_XZ = getappdata(0, 'Ez_XZ');
            Ez_YZ = getappdata(0, 'Ez_YZ');
            Ez_XY = getappdata(0, 'Ez_XY');  
            rho = getappdata(0,'rho');
            cd(app.FolderEditField.Value);
            if ~exist(app.NameEditField.Value,'dir')
                mkdir(app.NameEditField.Value);
            end
            cd(app.NameEditField.Value);
            %dlmwrite ('I_fout.dat',I_fout, 'Delimiter', '\t');
            dlmwrite ('Ex_XZ.dat', Ex_XZ, 'Delimiter', '\t');
            dlmwrite ('Ex_YZ.dat', Ex_YZ, 'Delimiter', '\t');
            dlmwrite ('Ex_XY.dat', Ex_XY, 'Delimiter', '\t');
            dlmwrite ('Ey_XZ.dat', Ey_XZ, 'Delimiter', '\t');
            dlmwrite ('Ey_YZ.dat', Ey_YZ, 'Delimiter', '\t');
            dlmwrite ('Ey_XY.dat', Ey_XY, 'Delimiter', '\t');
            dlmwrite ('Ez_XZ.dat', Ez_XZ, 'Delimiter', '\t');
            dlmwrite ('Ez_YZ.dat', Ez_YZ, 'Delimiter', '\t');
            dlmwrite ('Ez_XY.dat', Ez_XY, 'Delimiter', '\t');            
            fid = fopen('pixelRatio.txt','wt');
            fprintf(fid,'rhoXY = %0.3f\n', rho(1)*1e6);
            fprintf(fid,'rhoZ = %0.3f\n', rho(2)*1e6);
            fprintf(fid,'unit: um/pixel');
            cd ..;           
        end

        % Button pushed function: ExportIntensityVolumexyzButton
        function ExportIntensityVolumexyzButtonPushed(app, event)
            I_fout = getappdata(0, 'I_fout');
            rho    = getappdata(0,'rho');
            cd(app.FolderEditField.Value);
            if ~exist(app.NameEditField.Value,'dir')
                mkdir(app.NameEditField.Value);
            end
            cd(app.NameEditField.Value);
            save ('I_fout.mat','I_fout');
            fid = fopen('pixelRatio.txt','wt');
            fprintf(fid,'rhoXY = %0.3f\n', rho(1)*1e6);
            fprintf(fid,'rhoZ = %0.3f\n', rho(2)*1e6);
            fprintf(fid,'unit: um/pixel');
            cd ..;             
        end

        % Button pushed function: ExportFieldVolumexyzButton
        function ExportFieldVolumexyzButtonPushed(app, event)
            E_fxout = getappdata(0, 'E_fxout');
            E_fyout = getappdata(0, 'E_fyout');
            E_fzout = getappdata(0, 'E_fzout');
            rho    = getappdata(0,'rho');
            cd(app.FolderEditField.Value);
            if ~exist(app.NameEditField.Value,'dir')
                mkdir(app.NameEditField.Value);
            end
            cd(app.NameEditField.Value);
            save ('E_fxout.mat','E_fxout');
            save ('E_fyout.mat','E_fyout');
            save ('E_fzout.mat','E_fzout');
            fid = fopen('pixelRatio.txt','wt');
            fprintf(fid,'rhoXY = %0.3f\n', rho(1)*1e6);
            fprintf(fid,'rhoZ = %0.3f\n', rho(2)*1e6);
            fprintf(fid,'unit: um/pixel');
            cd ..;              
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FileTab = uitab(app.TabGroup);
            app.FileTab.Title = 'File';
            app.NameEditFieldLabel = uilabel(app.FileTab);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.FileTab, 'text');
            app.NameEditField.ValueChangedFcn = createCallbackFcn(app, @NameEditFieldValueChanged, true);
            app.NameEditField.Value = 'Simulation Gaussian C140TMD-C air';
            app.PlotPanel = uipanel(app.FileTab);
            app.PlotPanel.Title = 'Plot';
            app.ColormapDropDownLabel = uilabel(app.PlotPanel);
            app.ColormapDropDownLabel.Text = 'Colormap';
            app.ColormapDropDown = uidropdown(app.PlotPanel);
            app.ColormapDropDown.Items = {'fire', 'hot', 'jet', 'summer'};
            app.ColormapDropDown.Value = 'fire';
            app.SavePlotButton = uibutton(app.PlotPanel, 'push');
            app.SavePlotButton.ButtonPushedFcn = createCallbackFcn(app, @SavePlotButtonPushed, true);
            app.SavePlotButton.Text = 'Save Plot';
            app.FolderEditFieldLabel = uilabel(app.FileTab);
            app.FolderEditFieldLabel.Text = 'Folder';
            app.FolderEditField = uieditfield(app.FileTab, 'text');
            app.FolderEditField.Value = 'X:\Private\02 Project-LinearPropagation\experimentSimulationCompare\ShapedBeam';
            app.ExportIntensityxyxzyzButton = uibutton(app.FileTab, 'push');
            app.ExportIntensityxyxzyzButton.ButtonPushedFcn = createCallbackFcn(app, @ExportIntensityxyxzyzButtonPushed, true);
            app.ExportIntensityxyxzyzButton.Text = 'Export Intensity (xy, xz, yz)';
            app.ExportComplexFieldxyxzyzButton = uibutton(app.FileTab, 'push');
            app.ExportComplexFieldxyxzyzButton.ButtonPushedFcn = createCallbackFcn(app, @ExportComplexFieldxyxzyzButtonPushed, true);
            app.ExportComplexFieldxyxzyzButton.Text = 'Export Complex Field (xy, xz, yz)';
            app.ExportIntensityVolumexyzButton = uibutton(app.FileTab, 'push');
            app.ExportIntensityVolumexyzButton.ButtonPushedFcn = createCallbackFcn(app, @ExportIntensityVolumexyzButtonPushed, true);
            app.ExportIntensityVolumexyzButton.Text = 'Export Intensity Volume (xyz)';
            app.ExportFieldVolumexyzButton = uibutton(app.FileTab, 'push');
            app.ExportFieldVolumexyzButton.ButtonPushedFcn = createCallbackFcn(app, @ExportFieldVolumexyzButtonPushed, true);
            app.ExportFieldVolumexyzButton.Text = 'Export Field Volume (xyz)';
            app.SettingTab = uitab(app.TabGroup);
            app.SettingTab.Title = 'Setting';
            app.InputsamplingLabel = uilabel(app.SettingTab);
            app.InputsamplingLabel.Text = 'Input sampling:';
            app.pixelWHEditFieldLabel = uilabel(app.SettingTab);
            app.pixelWHEditFieldLabel.Text = 'pixelWH';
            app.pixelWHEditField = uieditfield(app.SettingTab, 'numeric');
            app.pixelWHEditField.ValueChangedFcn = createCallbackFcn(app, @pixelWHEditFieldValueChanged, true);
            app.pixelWHEditField.Value = 501;
            app.physicalWHmmEditFieldLabel = uilabel(app.SettingTab);
            app.physicalWHmmEditFieldLabel.Text = 'physicalWH (mm)';
            app.physicalWHmmEditField = uieditfield(app.SettingTab, 'numeric');
            app.physicalWHmmEditField.ValueChangedFcn = createCallbackFcn(app, @physicalWHmmEditFieldValueChanged, true);
            app.physicalWHmmEditField.Value = 30;
            app.BeamtypeDropDownLabel = uilabel(app.SettingTab);
            app.BeamtypeDropDownLabel.Text = 'Beam type';
            app.BeamtypeDropDown = uidropdown(app.SettingTab);
            app.BeamtypeDropDown.Items = {'Gauss', 'Tophat', 'Vortex', 'Bessel', 'User define'};
            app.BeamtypeDropDown.ValueChangedFcn = createCallbackFcn(app, @BeamtypeDropDownValueChanged, true);
            app.BeamtypeDropDown.Value = 'Bessel';
            app.LaserLabel = uilabel(app.SettingTab);
            app.LaserLabel.Text = 'Laser:';
            app.WavelengthnmEditFieldLabel = uilabel(app.SettingTab);
            app.WavelengthnmEditFieldLabel.Text = 'Wavelength (nm)';
            app.WavelengthnmEditField = uieditfield(app.SettingTab, 'numeric');
            app.WavelengthnmEditField.ValueChangedFcn = createCallbackFcn(app, @WavelengthnmEditFieldValueChanged, true);
            app.WavelengthnmEditField.Value = 1555;
            app.PolarizationEditFieldLabel = uilabel(app.SettingTab);
            app.PolarizationEditFieldLabel.Text = 'Polarization';
            app.PolarizationEditField = uieditfield(app.SettingTab, 'text');
            app.PolarizationEditField.ValueChangedFcn = createCallbackFcn(app, @PolarizationEditFieldValueChanged, true);
            app.PolarizationEditField.ValueChangingFcn = createCallbackFcn(app, @PolarizationEditFieldValueChanging, true);
            app.PolarizationEditField.Value = 'linear_x';
            app.LensLabel = uilabel(app.SettingTab);
            app.LensLabel.Text = 'Lens:';
            app.fmmEditFieldLabel = uilabel(app.SettingTab);
            app.fmmEditFieldLabel.Text = 'f (mm)';
            app.fmmEditField = uieditfield(app.SettingTab, 'numeric');
            app.fmmEditField.ValueChangedFcn = createCallbackFcn(app, @fmmEditFieldValueChanged, true);
            app.fmmEditField.Value = 20;
            app.NAEditFieldLabel = uilabel(app.SettingTab);
            app.NAEditFieldLabel.Text = 'NA';
            app.NAEditField = uieditfield(app.SettingTab, 'numeric');
            app.NAEditField.ValueChangedFcn = createCallbackFcn(app, @NAEditFieldValueChanged, true);
            app.NAEditField.Value = 0.26;
            app.MaterialsLabel = uilabel(app.SettingTab);
            app.MaterialsLabel.Text = 'Materials:';
            app.n1EditFieldLabel = uilabel(app.SettingTab);
            app.n1EditFieldLabel.Text = 'n1';
            app.n1EditField = uieditfield(app.SettingTab, 'numeric');
            app.n1EditField.ValueChangedFcn = createCallbackFcn(app, @n1EditFieldValueChanged, true);
            app.n1EditField.Value = 1;
            app.n2EditFieldLabel = uilabel(app.SettingTab);
            app.n2EditFieldLabel.Text = 'n2';
            app.n2EditField = uieditfield(app.SettingTab, 'numeric');
            app.n2EditField.ValueChangedFcn = createCallbackFcn(app, @n2EditFieldValueChanged, true);
            app.n2EditField.Value = 3.475;
            app.dmmEditFieldLabel = uilabel(app.SettingTab);
            app.dmmEditFieldLabel.Text = 'd (mm)';
            app.dmmEditField = uieditfield(app.SettingTab, 'numeric');
            app.dmmEditField.ValueChangedFcn = createCallbackFcn(app, @dmmEditFieldValueChanged, true);
            app.EntrancepupilrmmEditFieldLabel = uilabel(app.SettingTab);
            app.EntrancepupilrmmEditFieldLabel.Text = 'Entrance pupil r (mm)';
            app.EntrancepupilrmmEditField = uieditfield(app.SettingTab, 'numeric');
            app.EntrancepupilrmmEditField.ValueChangedFcn = createCallbackFcn(app, @EntrancepupilrmmEditFieldValueChanged, true);
            app.EntrancepupilrmmEditField.Value = 5.2;
            app.AberrationLabel = uilabel(app.SettingTab);
            app.AberrationLabel.Text = 'Aberration:';
            app.TabGroup2 = uitabgroup(app.SettingTab);
            app.TabGroup2.SelectionChangedFcn = createCallbackFcn(app, @TabGroup2SelectionChanged, true);
            app.GaussTab = uitab(app.TabGroup2);
            app.GaussTab.Title = 'Gauss';
            app.WxmmEditFieldLabel = uilabel(app.GaussTab);
            app.WxmmEditFieldLabel.Text = 'Wx (mm)';
            app.WxmmEditField = uieditfield(app.GaussTab, 'numeric');
            app.WxmmEditField.ValueChangedFcn = createCallbackFcn(app, @WxmmEditFieldValueChanged, true);
            app.WxmmEditField.Value = 4;
            app.WymmEditFieldLabel = uilabel(app.GaussTab);
            app.WymmEditFieldLabel.Text = 'Wy (mm)';
            app.WymmEditField = uieditfield(app.GaussTab, 'numeric');
            app.WymmEditField.ValueChangedFcn = createCallbackFcn(app, @WymmEditFieldValueChanged, true);
            app.WymmEditField.Value = 4;
            app.VortexTab = uitab(app.TabGroup2);
            app.VortexTab.Title = 'Vortex';
            app.WxmmEditField_2Label = uilabel(app.VortexTab);
            app.WxmmEditField_2Label.Text = 'Wx (mm)';
            app.WxmmEditField_2 = uieditfield(app.VortexTab, 'numeric');
            app.WxmmEditField_2.ValueChangedFcn = createCallbackFcn(app, @WxmmEditField_2ValueChanged, true);
            app.WxmmEditField_2.Value = 4;
            app.WymmEditField_2Label = uilabel(app.VortexTab);
            app.WymmEditField_2Label.Text = 'Wy (mm)';
            app.WymmEditField_2 = uieditfield(app.VortexTab, 'numeric');
            app.WymmEditField_2.ValueChangedFcn = createCallbackFcn(app, @WymmEditField_2ValueChanged, true);
            app.WymmEditField_2.Value = 4;
            app.mEditFieldLabel = uilabel(app.VortexTab);
            app.mEditFieldLabel.Text = 'm';
            app.mEditField = uieditfield(app.VortexTab, 'numeric');
            app.mEditField.ValueChangedFcn = createCallbackFcn(app, @mEditFieldValueChanged, true);
            app.mEditField.Value = 1;
            app.BesselTab = uitab(app.TabGroup2);
            app.BesselTab.Title = 'Bessel';
            app.WxmmEditField_3Label = uilabel(app.BesselTab);
            app.WxmmEditField_3Label.Text = 'Wx (mm)';
            app.WxmmEditField_3 = uieditfield(app.BesselTab, 'numeric');
            app.WxmmEditField_3.Value = 4;
            app.WymmEditField_3Label = uilabel(app.BesselTab);
            app.WymmEditField_3Label.Text = 'Wy (mm)';
            app.WymmEditField_3 = uieditfield(app.BesselTab, 'numeric');
            app.WymmEditField_3.Value = 4;
            app.angledegEditFieldLabel = uilabel(app.BesselTab);
            app.angledegEditFieldLabel.Text = 'angle (deg)';
            app.angledegEditField = uieditfield(app.BesselTab, 'numeric');
            app.angledegEditField.Value = 1;
            app.UserdefineTab = uitab(app.TabGroup2);
            app.UserdefineTab.Title = 'User define';
            app.UserdefineprofilefolderEditFieldLabel = uilabel(app.UserdefineTab);
            app.UserdefineprofilefolderEditFieldLabel.Text = 'User define profile folder';
            app.UserdefineprofilefolderEditField = uieditfield(app.UserdefineTab, 'text');
            app.UserdefineprofilefolderEditField.ValueChangedFcn = createCallbackFcn(app, @UserdefineprofilefolderEditFieldValueChanged, true);
            app.UserdefineprofilefolderEditField.Value = 'X:\Private\02 Project-LinearPropagation\Results linear propagation\inputBeam\20200606\Vortex';
            app.SamplingEditFieldLabel = uilabel(app.UserdefineTab);
            app.SamplingEditFieldLabel.Text = 'Sampling';
            app.SamplingEditField = uieditfield(app.UserdefineTab, 'numeric');
            app.SamplingEditField.ValueChangedFcn = createCallbackFcn(app, @SamplingEditFieldValueChanged, true);
            app.SamplingEditField.Value = 501;
            app.PixelmEditFieldLabel = uilabel(app.UserdefineTab);
            app.PixelmEditFieldLabel.Text = 'Pixel/µm';
            app.PixelmEditField = uieditfield(app.UserdefineTab, 'numeric');
            app.PixelmEditField.ValueChangedFcn = createCallbackFcn(app, @PixelmEditFieldValueChanged, true);
            app.PixelmEditField.Value = 5.2;
            app.ThresholdEditFieldLabel = uilabel(app.UserdefineTab);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.UserdefineTab, 'numeric');
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.ThresholdEditField.Value = 0.08;
            app.Z1EditFieldLabel = uilabel(app.SettingTab);
            app.Z1EditFieldLabel.Text = 'Z1';
            app.Z1EditField = uieditfield(app.SettingTab, 'numeric');
            app.Z1EditField.ValueChangedFcn = createCallbackFcn(app, @Z1EditFieldValueChanged, true);
            app.Z1EditField.Value = 0.5821;
            app.Z4EditFieldLabel = uilabel(app.SettingTab);
            app.Z4EditFieldLabel.Text = 'Z4';
            app.Z4EditField = uieditfield(app.SettingTab, 'numeric');
            app.Z4EditField.ValueChangedFcn = createCallbackFcn(app, @Z4EditFieldValueChanged, true);
            app.Z4EditField.Value = 0.3187;
            app.Z11EditFieldLabel = uilabel(app.SettingTab);
            app.Z11EditFieldLabel.Text = 'Z11';
            app.Z11EditField = uieditfield(app.SettingTab, 'numeric');
            app.Z11EditField.ValueChangedFcn = createCallbackFcn(app, @Z11EditFieldValueChanged, true);
            app.Z11EditField.Value = -0.0209;
            app.Z22EditFieldLabel = uilabel(app.SettingTab);
            app.Z22EditFieldLabel.Text = 'Z22';
            app.Z22EditField = uieditfield(app.SettingTab, 'numeric');
            app.Z22EditField.ValueChangedFcn = createCallbackFcn(app, @Z22EditFieldValueChanged, true);
            app.Z22EditField.Value = -0.007;
            app.Z37EditFieldLabel = uilabel(app.SettingTab);
            app.Z37EditFieldLabel.Text = 'Z37';
            app.Z37EditField = uieditfield(app.SettingTab, 'numeric');
            app.Z37EditField.ValueChangedFcn = createCallbackFcn(app, @Z37EditFieldValueChanged, true);
            app.Z37EditField.Value = -0.007;
            app.CalculationTab = uitab(app.TabGroup);
            app.CalculationTab.Title = 'Calculation';
            app.OutputsamplingLabel = uilabel(app.CalculationTab);
            app.OutputsamplingLabel.Text = 'Output sampling';
            app.pixelOutXEditField_2Label = uilabel(app.CalculationTab);
            app.pixelOutXEditField_2Label.Text = 'pixelOutX';
            app.pixelOutXEditField = uieditfield(app.CalculationTab, 'numeric');
            app.pixelOutXEditField.Value = 151;
            app.xOut1mEditFieldLabel = uilabel(app.CalculationTab);
            app.xOut1mEditFieldLabel.Text = 'xOut1 (µm)';
            app.xOut1EditField = uieditfield(app.CalculationTab, 'numeric');
            app.xOut1EditField.Value = -15;
            app.xOut2mEditFieldLabel = uilabel(app.CalculationTab);
            app.xOut2mEditFieldLabel.Text = 'xOut2 (µm)';
            app.xOut2EditField = uieditfield(app.CalculationTab, 'numeric');
            app.xOut2EditField.Value = 15;
            app.pixelOutZEditField_2Label = uilabel(app.CalculationTab);
            app.pixelOutZEditField_2Label.Text = 'pixelOutZ';
            app.pixelOutZEditField = uieditfield(app.CalculationTab, 'numeric');
            app.pixelOutZEditField.ValueChangedFcn = createCallbackFcn(app, @pixelOutZEditFieldValueChanged, true);
            app.pixelOutZEditField.Value = 801;
            app.zOut1mEditFieldLabel = uilabel(app.CalculationTab);
            app.zOut1mEditFieldLabel.Text = 'zOut1 (µm)';
            app.zOut1EditField = uieditfield(app.CalculationTab, 'numeric');
            app.zOut1EditField.ValueChangedFcn = createCallbackFcn(app, @zOut1EditFieldValueChanged, true);
            app.zOut1EditField.Value = -200;
            app.zOut2mEditFieldLabel = uilabel(app.CalculationTab);
            app.zOut2mEditFieldLabel.Text = 'zOut2 (µm)';
            app.zOut2EditField = uieditfield(app.CalculationTab, 'numeric');
            app.zOut2EditField.ValueChangedFcn = createCallbackFcn(app, @zOut2EditFieldValueChanged, true);
            app.zOut2EditField.Value = 200;
            app.AOIz1mEditFieldLabel = uilabel(app.CalculationTab);
            app.AOIz1mEditFieldLabel.Text = 'AOIz1(µm)';
            app.AOIz1mEditField = uieditfield(app.CalculationTab, 'numeric');
            app.AOIz1mEditField.ValueChangedFcn = createCallbackFcn(app, @AOIz1mEditFieldValueChanged, true);
            app.AOIz1mEditField.Value = -200;
            app.AOIz2mEditFieldLabel = uilabel(app.CalculationTab);
            app.AOIz2mEditFieldLabel.Text = 'AOIz2(µm)';
            app.AOIz2mEditField = uieditfield(app.CalculationTab, 'numeric');
            app.AOIz2mEditField.ValueChangedFcn = createCallbackFcn(app, @AOIz2mEditFieldValueChanged, true);
            app.AOIz2mEditField.Value = 200;
            app.AOIx1mEditFieldLabel = uilabel(app.CalculationTab);
            app.AOIx1mEditFieldLabel.Text = 'AOIx1(µm)';
            app.AOIx1mEditField = uieditfield(app.CalculationTab, 'numeric');
            app.AOIx1mEditField.Value = -10;
            app.AOIx2mEditFieldLabel = uilabel(app.CalculationTab);
            app.AOIx2mEditFieldLabel.Text = 'AOIx2(µm)';
            app.AOIx2mEditField = uieditfield(app.CalculationTab, 'numeric');
            app.AOIx2mEditField.Value = 10;
            app.xzAxesRatioEditFieldLabel = uilabel(app.CalculationTab);
            app.xzAxesRatioEditFieldLabel.Text = 'xzAxesRatio';
            app.xzAxesRatioEditField = uieditfield(app.CalculationTab, 'numeric');
            app.xzAxesRatioEditField.Value = 3.71;
            app.AreaoftheinterestLabel = uilabel(app.CalculationTab);
            app.AreaoftheinterestLabel.Text = 'Area of the interest';
            app.StatsTab = uitab(app.TabGroup);
            app.StatsTab.Title = 'Stats';
            app.BeamWaistmTextAreaLabel = uilabel(app.StatsTab);
            app.BeamWaistmTextAreaLabel.Text = 'BeamWaist (µm)';
            app.BeamWaistmTextArea = uitextarea(app.StatsTab);
            app.BeamWaistmTextArea.Editable = 'off';
            app.UpdateButton = uibutton(app.StatsTab, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.RayleighLengthmTextAreaLabel = uilabel(app.StatsTab);
            app.RayleighLengthmTextAreaLabel.Text = 'RayleighLength (µm)';
            app.RayleighLengthmTextArea = uitextarea(app.StatsTab);
            app.RayleighLengthmTextArea.Editable = 'off';
            app.fmmTextAreaLabel = uilabel(app.StatsTab);
            app.fmmTextAreaLabel.Text = 'f (mm)';
            app.fmmTextArea = uitextarea(app.StatsTab);
            app.fmmTextArea.Editable = 'off';
            app.NATextAreaLabel = uilabel(app.StatsTab);
            app.NATextAreaLabel.Text = 'NA';
            app.NATextArea = uitextarea(app.StatsTab);
            app.NATextArea.Editable = 'off';
            app.n1TextAreaLabel = uilabel(app.StatsTab);
            app.n1TextAreaLabel.Text = 'n1';
            app.n1TextArea = uitextarea(app.StatsTab);
            app.n1TextArea.Editable = 'off';
            app.n2TextAreaLabel = uilabel(app.StatsTab);
            app.n2TextAreaLabel.Text = 'n2';
            app.n2TextArea = uitextarea(app.StatsTab);
            app.n2TextArea.Editable = 'off';
            app.dmmTextAreaLabel = uilabel(app.StatsTab);
            app.dmmTextAreaLabel.Text = 'd (mm)';
            app.dmmTextArea = uitextarea(app.StatsTab);
            app.dmmTextArea.Editable = 'off';
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
            app.CopyrightCopyrightc2020QingfengLiAllrightsreservedLabel = uilabel(app.AboutTab);
            app.CopyrightCopyrightc2020QingfengLiAllrightsreservedLabel.Text = {' Copyright'; ' Copyright  (c) 2020 Qingfeng Li.'; ' All rights reserved.'};
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Input Intensity ')
            xlabel(app.UIAxes, 'X [mm]')
            ylabel(app.UIAxes, 'Y [mm]')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Output Intensity ')
            xlabel(app.UIAxes_2, 'X [µm]')
            ylabel(app.UIAxes_2, 'Y [µm]')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Intensity profile along z')
            xlabel(app.UIAxes_3, 'Z [µm]')
            ylabel(app.UIAxes_3, 'Intensity [a.u.]')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Intensity distribution XZ')
            xlabel(app.UIAxes_4, 'Z [µm]')
            ylabel(app.UIAxes_4, 'X [µm]')
            app.PSFcalulateButton = uibutton(app.UIFigure, 'push');
            app.PSFcalulateButton.ButtonPushedFcn = createCallbackFcn(app, @PSFcalulateButtonPushed, true);
            app.PSFcalulateButton.Text = 'PSF calulate';
            app.InitializeButton = uibutton(app.UIFigure, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeButtonPushed, true);
            app.InitializeButton.Text = 'Initialize';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'log', 'linear'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'linear';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.UIAxes_5 = uiaxes(app.UIFigure);
            title(app.UIAxes_5, 'Intensity distribution YZ')
            xlabel(app.UIAxes_5, 'Z [µm]')
            ylabel(app.UIAxes_5, 'Y [µm]')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DisplayInputOutput

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end